classdef ControllerInterface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        CloseButton             matlab.ui.control.Button
        OpenButton              matlab.ui.control.Button
        Gripper                 matlab.ui.control.Label
        ConnectButton           matlab.ui.control.StateButton
        MoveAllJointsButton     matlab.ui.control.Button
        MoveAllButton           matlab.ui.control.Button
        MoveJ6Button            matlab.ui.control.Button
        desiredJ6               matlab.ui.control.NumericEditField
        robotJ6                 matlab.ui.control.Label
        J6Label                 matlab.ui.control.Label
        MoveJ5Button            matlab.ui.control.Button
        desiredJ5               matlab.ui.control.NumericEditField
        robotJ5                 matlab.ui.control.Label
        J5Label                 matlab.ui.control.Label
        MoveJ4Button            matlab.ui.control.Button
        desiredJ4               matlab.ui.control.NumericEditField
        robotJ4                 matlab.ui.control.Label
        J4Label                 matlab.ui.control.Label
        MoveJ3Button            matlab.ui.control.Button
        desiredJ3               matlab.ui.control.NumericEditField
        robotJ3                 matlab.ui.control.Label
        J3Label                 matlab.ui.control.Label
        MoveJ2Button            matlab.ui.control.Button
        desiredJ2               matlab.ui.control.NumericEditField
        robotJ2                 matlab.ui.control.Label
        J2Label                 matlab.ui.control.Label
        MoveJ1Button            matlab.ui.control.Button
        desiredJ1               matlab.ui.control.NumericEditField
        robotJ1                 matlab.ui.control.Label
        J1Label                 matlab.ui.control.Label
        MoveRzButton            matlab.ui.control.Button
        desiredRz               matlab.ui.control.NumericEditField
        robotRz                 matlab.ui.control.Label
        RzLabel                 matlab.ui.control.Label
        MoveRyButton            matlab.ui.control.Button
        desiredRy               matlab.ui.control.NumericEditField
        robotRy                 matlab.ui.control.Label
        RyLabel                 matlab.ui.control.Label
        MoveRxButton            matlab.ui.control.Button
        desiredRx               matlab.ui.control.NumericEditField
        robotRx                 matlab.ui.control.Label
        RxLabel                 matlab.ui.control.Label
        MoveZButton             matlab.ui.control.Button
        desiredZ                matlab.ui.control.NumericEditField
        robotZ                  matlab.ui.control.Label
        ZLabel                  matlab.ui.control.Label
        MoveYButton             matlab.ui.control.Button
        desiredY                matlab.ui.control.NumericEditField
        robotY                  matlab.ui.control.Label
        YLabel                  matlab.ui.control.Label
        MoveXButton             matlab.ui.control.Button
        desiredX                matlab.ui.control.NumericEditField
        robotX                  matlab.ui.control.Label
        XLabel                  matlab.ui.control.Label
        JointSpaceLabel         matlab.ui.control.Label
        CartesianSpacemmLabel   matlab.ui.control.Label
        VerC                    matlab.ui.control.Label
        IP                      matlab.ui.control.EditField
        RobotsIPEditFieldLabel  matlab.ui.control.Label
        DOLabel                 matlab.ui.control.Label
        DO10                    matlab.ui.control.Switch
        DO9                     matlab.ui.control.Switch
        DO8                     matlab.ui.control.Switch
        DO7                     matlab.ui.control.Switch
        DO6                     matlab.ui.control.Switch
        DO5                     matlab.ui.control.Switch
        DI10                    matlab.ui.control.Lamp
        Label_11                matlab.ui.control.Label
        DI9                     matlab.ui.control.Lamp
        Label_10                matlab.ui.control.Label
        DI8                     matlab.ui.control.Lamp
        Label_9                 matlab.ui.control.Label
        DI7                     matlab.ui.control.Lamp
        Label_8                 matlab.ui.control.Label
        DI6                     matlab.ui.control.Lamp
        Label_7                 matlab.ui.control.Label
        DI5                     matlab.ui.control.Lamp
        Label_6                 matlab.ui.control.Label
        DI4                     matlab.ui.control.Lamp
        Label_5                 matlab.ui.control.Label
        DI3                     matlab.ui.control.Lamp
        Label_4                 matlab.ui.control.Label
        DI2                     matlab.ui.control.Lamp
        Label_3                 matlab.ui.control.Label
        DI1                     matlab.ui.control.Lamp
        Label_2                 matlab.ui.control.Label
        SpeedListBox            matlab.ui.control.ListBox
        SpeedListBoxLabel       matlab.ui.control.Label
        DILabel                 matlab.ui.control.Label
        DO4                     matlab.ui.control.Switch
        DO3                     matlab.ui.control.Switch
        DO2                     matlab.ui.control.Switch
        DO1                     matlab.ui.control.Switch
        DO0                     matlab.ui.control.Switch
        DI0                     matlab.ui.control.Lamp
        LampLabel               matlab.ui.control.Label
    end

    
    properties (Access = private)
        tm5_700=Class_TMRobot();
        flagsDO=[0 0 0 0 0 0 0 0 0 0 0];
        updatetimer=timer('Period', 0.1,'ExecutionMode', 'fixedSpacing','BusyMode','queue');
    end
    
    methods (Access = public)
        
        function update(app)

            app.UIFigure.Interruptible='off';
            app.UIFigure.BusyAction='queue';

             if(isobject(app.tm5_700.modbusClient) && isobject(app.tm5_700.tcpClient)) 
                
                for i=1:11
                   state=app.tm5_700.getDI_c(i-1);
                   if state==1
                    app.(['DI' num2str(i-1)]).Color='green';
                   else
                    app.(['DI' num2str(i-1)]).Color='red';
                   end
                end 
    
                coords=app.tm5_700.getRealpos_c();
                app.robotX.Text=num2str(coords(1));
                app.robotY.Text=num2str(coords(2));
                app.robotZ.Text=num2str(coords(3));
                app.robotRx.Text=num2str(coords(4));
                app.robotRy.Text=num2str(coords(5));
                app.robotRz.Text=num2str(coords(6));
                
                q=app.tm5_700.getRealjoints_c();
                app.robotJ1.Text=num2str(q(1));
                app.robotJ2.Text=num2str(q(2));
                app.robotJ3.Text=num2str(q(3));
                app.robotJ4.Text=num2str(q(4));
                app.robotJ5.Text=num2str(q(5));
                app.robotJ6.Text=num2str(q(6));
    
                clear updateDO;
                n=1;
    
                for i=1:11
                    if app.flagsDO(i)==1
                        updateDO(n)=i-1;
                        n=n+1;
                        app.flagsDO(i)=0;
                    end
                end
    
                if exist('updateDO')
                    for i=1:length(updateDO)
                        value = app.(['DO' num2str(updateDO(i))]).Value;
                        if value == 'L'
                            app.tm5_700.setDO_c(updateDO(i),0);
                        else
                            app.tm5_700.setDO_c(updateDO(i),1);
                        end
                    end
                end

                if (app.ConnectButton.Value==1 && isobject(app.tm5_700.modbusClient) && isobject(app.tm5_700.tcpClient))
                    app.VerC.Text='Connected to robot';
                else
                    app.VerC.Text='Not Connected to robot';
                end
            else
                app.VerC.Text='Not Connected to robot';
             end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.updatetimer.TimerFcn=@(~,~)app.update;
        end

        % Value changed function: DO0
        function DO0ValueChanged(app, event)
            if(app.VerC.Text=="Connected to robot")
                app.flagsDO(1)=1;
            else
                app.VerC.Text='Not Connected to robot';
            end
        end

        % Value changed function: DO1
        function DO1ValueChanged(app, event)
            if(app.VerC.Text=="Connected to robot")
                app.flagsDO(2)=1;
            else
                app.VerC.Text='Not Connected to robot';
            end     
        end

        % Value changed function: DO2
        function DO2ValueChanged(app, event)
            if(app.VerC.Text=="Connected to robot")
                app.flagsDO(3)=1;
            else
                app.VerC.Text='Not Connected to robot';
            end
        end

        % Value changed function: DO3
        function DO3ValueChanged(app, event)
            if(app.VerC.Text=="Connected to robot")
                app.flagsDO(4)=1;
            else
                app.VerC.Text='Not Connected to robot';
            end
        end

        % Value changed function: DO4
        function DO4ValueChanged(app, event)
            if(app.VerC.Text=="Connected to robot")
                app.flagsDO(5)=1;
            else
                app.VerC.Text='Not Connected to robot';
            end
        end

        % Value changed function: DO5
        function DO5ValueChanged(app, event)
            if(app.VerC.Text=="Connected to robot")
                app.flagsDO(6)=1;
            else
                app.VerC.Text='Not Connected to robot';
            end
        end

        % Value changed function: DO6
        function DO6ValueChanged(app, event)
            if(app.VerC.Text=="Connected to robot")
                app.flagsDO(7)=1;
            else
                app.VerC.Text='Not Connected to robot';
            end
        end

        % Value changed function: DO7
        function DO7ValueChanged(app, event)
            if(app.VerC.Text=="Connected to robot")
                app.flagsDO(8)=1;
            else
                app.VerC.Text='Not Connected to robot';
            end
        end

        % Value changed function: DO8
        function DO8ValueChanged(app, event)
            if(app.VerC.Text=="Connected to robot")
                app.flagsDO(9)=1;
            else
                app.VerC.Text='Not Connected to robot';
            end
        end

        % Value changed function: DO9
        function DO9ValueChanged(app, event)
            if(app.VerC.Text=="Connected to robot")
                app.flagsDO(10)=1;
            else
                app.VerC.Text='Not Connected to robot';
            end
        end

        % Value changed function: DO10
        function DO10ValueChanged(app, event)
            if(app.VerC.Text=="Connected to robot")
                app.flagsDO(11)=1;
            else
                app.VerC.Text='Not Connected to robot';
            end
        end

        % Value changed function: ConnectButton
        function ConnectButtonValueChanged(app, event)
            value = app.ConnectButton.Value;
            stop(app.updatetimer);
            if(value==1)
                if(~isempty(app.IP.Value))
                    app.tm5_700.iprobot=(app.IP.Value);
                    app.tm5_700=app.tm5_700.connect2controller_c();
                else
                    app.tm5_700=app.tm5_700.connect2controller_c();
                    
                end

                if(isobject(app.tm5_700.modbusClient) && isobject(app.tm5_700.tcpClient))
                    app.VerC.Text='Connected to robot';
                    start(app.updatetimer);
                end
                
            else
                app.tm5_700=Class_TMRobot();
                stop(app.updatetimer);
                app.VerC.Text='Not Connected to robot';
            end
        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            if(app.VerC.Text=="Connected to robot")
                stop(app.updatetimer);
                app.tm5_700.opengripper_c();
                start(app.updatetimer);
            else
               app.VerC.Text='Not Connected to robot';
            end
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            if(app.VerC.Text=="Connected to robot")
                stop(app.updatetimer);
                app.tm5_700.closegripper_c();
                start(app.updatetimer);
            else
               app.VerC.Text='Not Connected to robot';
            end
        end

        % Button pushed function: MoveXButton
        function MoveXButtonPushed(app, event)
            if(app.VerC.Text=="Connected to robot")
                app.tm5_700.LineMove2pos_c([app.desiredX.Value str2double(app.robotY.Text) str2double(app.robotZ.Text) str2double(app.robotRx.Text) str2double(app.robotRy.Text) str2double(app.robotRz.Text)],str2double(app.SpeedListBox.Value));
            else
               app.VerC.Text='Not Connected to robot';
            end
        end

        % Button pushed function: MoveYButton
        function MoveYButtonPushed(app, event)
             if(app.VerC.Text=="Connected to robot")
                app.tm5_700.LineMove2pos_c([str2double(app.robotX.Text)  app.desiredY.Value str2double(app.robotZ.Text) str2double(app.robotRx.Text) str2double(app.robotRy.Text) str2double(app.robotRz.Text)],str2double(app.SpeedListBox.Value))
            else
               app.VerC.Text='Not Connected to robot';
             end
        end

        % Button pushed function: MoveZButton
        function MoveZButtonPushed(app, event)
             if(app.VerC.Text=="Connected to robot")
               app.tm5_700.LineMove2pos_c([str2double(app.robotX.Text) str2double(app.robotY.Text) app.desiredZ.Value str2double(app.robotRx.Text) str2double(app.robotRy.Text) str2double(app.robotRz.Text)],str2double(app.SpeedListBox.Value));
            else
               app.VerC.Text='Not Connected to robot';
            end
        end

        % Button pushed function: MoveRxButton
        function MoveRxButtonPushed(app, event)
            if(app.VerC.Text=="Connected to robot")
               app.tm5_700.LineMove2pos_c([str2double(app.robotX.Text) str2double(app.robotY.Text) str2double(app.robotZ.Text) app.desiredRx.Value str2double(app.robotRy.Text) str2double(app.robotRz.Text)],str2double(app.SpeedListBox.Value));
            else
               app.VerC.Text='Not Connected to robot';
            end
        end

        % Button pushed function: MoveRyButton
        function MoveRyButtonPushed(app, event)
             if(app.VerC.Text=="Connected to robot")
                app.tm5_700.LineMove2pos_c([str2double(app.robotX.Text) str2double(app.robotY.Text) str2double(app.robotZ.Text)  str2double(app.robotRx.Text) app.desiredRy.Value str2double(app.robotRz.Text)],str2double(app.SpeedListBox.Value));
            else
               app.VerC.Text='Not Connected to robot';
            end
        end

        % Button pushed function: MoveRzButton
        function MoveRzButtonPushed(app, event)
             if(app.VerC.Text=="Connected to robot")
                 app.tm5_700.LineMove2pos_c([str2double(app.robotX.Text) str2double(app.robotY.Text) str2double(app.robotZ.Text)  str2double(app.robotRx.Text) str2double(app.robotRy.Text) app.desiredRz.Value],str2double(app.SpeedListBox.Value));
            else
               app.VerC.Text='Not Connected to robot';
            end
        end

        % Button pushed function: MoveJ1Button
        function MoveJ1ButtonPushed(app, event)
            if(app.VerC.Text=="Connected to robot")
              app.tm5_700.Move2joint_c([app.desiredJ1.Value str2double(app.robotJ2.Text) str2double(app.robotJ3.Text) str2double(app.robotJ4.Text) str2double(app.robotJ5.Text) str2double(app.robotJ6.Text)],str2double(app.SpeedListBox.Value));
            else
              app.VerC.Text='Not Connected to robot';
            end
        end

        % Button pushed function: MoveJ2Button
        function MoveJ2ButtonPushed(app, event)
            if(app.VerC.Text=="Connected to robot")
                app.tm5_700.Move2joint_c([str2double(app.robotJ1.Text) app.desiredJ2.Value str2double(app.robotJ3.Text) str2double(app.robotJ4.Text) str2double(app.robotJ5.Text) str2double(app.robotJ6.Text)],str2double(app.SpeedListBox.Value));
            else
               app.VerC.Text='Not Connected to robot';
            end
        end

        % Button pushed function: MoveJ3Button
        function MoveJ3ButtonPushed(app, event)
            if(app.VerC.Text=="Connected to robot")
                app.tm5_700.Move2joint_c([str2double(app.robotJ1.Text) str2double(app.robotJ2.Text) app.desiredJ3.Value str2double(app.robotJ4.Text) str2double(app.robotJ5.Text) str2double(app.robotJ6.Text)],str2double(app.SpeedListBox.Value));
            else
               app.VerC.Text='Not Connected to robot';
            end
        end

        % Button pushed function: MoveJ4Button
        function MoveJ4ButtonPushed(app, event)
            if(app.VerC.Text=="Connected to robot")
                app.tm5_700.Move2joint_c([str2double(app.robotJ1.Text) str2double(app.robotJ2.Text) str2double(app.robotJ3.Text) app.desiredJ4.Value str2double(app.robotJ5.Text) str2double(app.robotJ6.Text)],str2double(app.SpeedListBox.Value));
            else
               app.VerC.Text='Not Connected to robot';
            end
        end

        % Button pushed function: MoveJ5Button
        function MoveJ5ButtonPushed(app, event)
            if(app.VerC.Text=="Connected to robot")
                app.tm5_700.Move2joint_c([str2double(app.robotJ1.Text) str2double(app.robotJ2.Text) str2double(app.robotJ3.Text) str2double(app.robotJ4.Text) app.desiredJ5.Value str2double(app.robotJ6.Text)],str2double(app.SpeedListBox.Value));
            else
               app.VerC.Text='Not Connected to robot';
            end
        end

        % Button pushed function: MoveJ6Button
        function MoveJ6ButtonPushed(app, event)
            if(app.VerC.Text=="Connected to robot")
                app.tm5_700.Move2joint_c([str2double(app.robotJ1.Text) str2double(app.robotJ2.Text) str2double(app.robotJ3.Text) str2double(app.robotJ4.Text) str2double(app.robotJ5.Text) app.desiredJ6.Value],str2double(app.SpeedListBox.Value));
            else
               app.VerC.Text='Not Connected to robot';
            end
        end

        % Button pushed function: MoveAllButton
        function MoveAllButtonPushed(app, event)
            if(app.VerC.Text=="Connected to robot")
                app.tm5_700.LineMove2pos_c([app.desiredX.Value app.desiredY.Value app.desiredZ.Value app.desiredRx.Value app.desiredRy.Value app.desiredRz.Value],str2double(app.SpeedListBox.Value));
            else
               app.VerC.Text='Not Connected to robot';
            end
        end

        % Button pushed function: MoveAllJointsButton
        function MoveAllJointsButtonPushed(app, event)
            if(app.VerC.Text=="Connected to robot")
                app.tm5_700.Move2joint_c([app.desiredJ1.Value app.desiredJ2.Value app.desiredJ3.Value app.desiredJ4.Value app.desiredJ5.Value app.desiredJ6.Value],str2double(app.SpeedListBox.Value));
            else
               app.VerC.Text='Not Connected to robot';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LampLabel = uilabel(app.UIFigure);
            app.LampLabel.Text = {'0'; ''};
            app.DI0 = uilamp(app.UIFigure);
            app.DO0 = uiswitch(app.UIFigure, 'slider');
            app.DO0.Items = {'L', 'H'};
            app.DO0.ValueChangedFcn = createCallbackFcn(app, @DO0ValueChanged, true);
            app.DO0.Value = 'L';
            app.DO1 = uiswitch(app.UIFigure, 'slider');
            app.DO1.Items = {'L', 'H'};
            app.DO1.ValueChangedFcn = createCallbackFcn(app, @DO1ValueChanged, true);
            app.DO1.Value = 'L';
            app.DO2 = uiswitch(app.UIFigure, 'slider');
            app.DO2.Items = {'L', 'H'};
            app.DO2.ValueChangedFcn = createCallbackFcn(app, @DO2ValueChanged, true);
            app.DO2.Value = 'L';
            app.DO3 = uiswitch(app.UIFigure, 'slider');
            app.DO3.Items = {'L', 'H'};
            app.DO3.ValueChangedFcn = createCallbackFcn(app, @DO3ValueChanged, true);
            app.DO3.Value = 'L';
            app.DO4 = uiswitch(app.UIFigure, 'slider');
            app.DO4.Items = {'L', 'H'};
            app.DO4.ValueChangedFcn = createCallbackFcn(app, @DO4ValueChanged, true);
            app.DO4.Value = 'L';
            app.DILabel = uilabel(app.UIFigure);
            app.DILabel.Text = 'DI:';
            app.SpeedListBoxLabel = uilabel(app.UIFigure);
            app.SpeedListBoxLabel.Text = 'Speed:';
            app.SpeedListBox = uilistbox(app.UIFigure);
            app.SpeedListBox.Items = {'10', '30', '50', '75', '100'};
            app.SpeedListBox.Value = '10';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '1';
            app.DI1 = uilamp(app.UIFigure);
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '2';
            app.DI2 = uilamp(app.UIFigure);
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '3';
            app.DI3 = uilamp(app.UIFigure);
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '4';
            app.DI4 = uilamp(app.UIFigure);
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '5';
            app.DI5 = uilamp(app.UIFigure);
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = '6';
            app.DI6 = uilamp(app.UIFigure);
            app.Label_8 = uilabel(app.UIFigure);
            app.Label_8.Text = '7';
            app.DI7 = uilamp(app.UIFigure);
            app.Label_9 = uilabel(app.UIFigure);
            app.Label_9.Text = '8';
            app.DI8 = uilamp(app.UIFigure);
            app.Label_10 = uilabel(app.UIFigure);
            app.Label_10.Text = '9';
            app.DI9 = uilamp(app.UIFigure);
            app.Label_11 = uilabel(app.UIFigure);
            app.Label_11.Text = '10';
            app.DI10 = uilamp(app.UIFigure);
            app.DO5 = uiswitch(app.UIFigure, 'slider');
            app.DO5.Items = {'L', 'H'};
            app.DO5.ValueChangedFcn = createCallbackFcn(app, @DO5ValueChanged, true);
            app.DO5.Value = 'L';
            app.DO6 = uiswitch(app.UIFigure, 'slider');
            app.DO6.Items = {'L', 'H'};
            app.DO6.ValueChangedFcn = createCallbackFcn(app, @DO6ValueChanged, true);
            app.DO6.Value = 'L';
            app.DO7 = uiswitch(app.UIFigure, 'slider');
            app.DO7.Items = {'L', 'H'};
            app.DO7.ValueChangedFcn = createCallbackFcn(app, @DO7ValueChanged, true);
            app.DO7.Value = 'L';
            app.DO8 = uiswitch(app.UIFigure, 'slider');
            app.DO8.Items = {'L', 'H'};
            app.DO8.ValueChangedFcn = createCallbackFcn(app, @DO8ValueChanged, true);
            app.DO8.Value = 'L';
            app.DO9 = uiswitch(app.UIFigure, 'slider');
            app.DO9.Items = {'L', 'H'};
            app.DO9.ValueChangedFcn = createCallbackFcn(app, @DO9ValueChanged, true);
            app.DO9.Value = 'L';
            app.DO10 = uiswitch(app.UIFigure, 'slider');
            app.DO10.Items = {'L', 'H'};
            app.DO10.ValueChangedFcn = createCallbackFcn(app, @DO10ValueChanged, true);
            app.DO10.Value = 'L';
            app.DOLabel = uilabel(app.UIFigure);
            app.DOLabel.Text = 'DO:';
            app.RobotsIPEditFieldLabel = uilabel(app.UIFigure);
            app.RobotsIPEditFieldLabel.Text = 'Robot''s IP:';
            app.IP = uieditfield(app.UIFigure, 'text');
            app.VerC = uilabel(app.UIFigure);
            app.VerC.Text = '';
            app.CartesianSpacemmLabel = uilabel(app.UIFigure);
            app.CartesianSpacemmLabel.Text = 'Cartesian Space: mm';
            app.JointSpaceLabel = uilabel(app.UIFigure);
            app.JointSpaceLabel.Text = 'Joint Space: º';
            app.XLabel = uilabel(app.UIFigure);
            app.XLabel.Text = 'X :';
            app.robotX = uilabel(app.UIFigure);
            app.robotX.Text = '';
            app.desiredX = uieditfield(app.UIFigure, 'numeric');
            app.MoveXButton = uibutton(app.UIFigure, 'push');
            app.MoveXButton.ButtonPushedFcn = createCallbackFcn(app, @MoveXButtonPushed, true);
            app.MoveXButton.Text = 'MoveX';
            app.YLabel = uilabel(app.UIFigure);
            app.YLabel.Text = 'Y :';
            app.robotY = uilabel(app.UIFigure);
            app.robotY.Text = '';
            app.desiredY = uieditfield(app.UIFigure, 'numeric');
            app.MoveYButton = uibutton(app.UIFigure, 'push');
            app.MoveYButton.ButtonPushedFcn = createCallbackFcn(app, @MoveYButtonPushed, true);
            app.MoveYButton.Text = 'MoveY';
            app.ZLabel = uilabel(app.UIFigure);
            app.ZLabel.Text = 'Z :';
            app.robotZ = uilabel(app.UIFigure);
            app.robotZ.Text = '';
            app.desiredZ = uieditfield(app.UIFigure, 'numeric');
            app.MoveZButton = uibutton(app.UIFigure, 'push');
            app.MoveZButton.ButtonPushedFcn = createCallbackFcn(app, @MoveZButtonPushed, true);
            app.MoveZButton.Text = 'MoveZ';
            app.RxLabel = uilabel(app.UIFigure);
            app.RxLabel.Text = 'Rx :';
            app.robotRx = uilabel(app.UIFigure);
            app.robotRx.Text = '';
            app.desiredRx = uieditfield(app.UIFigure, 'numeric');
            app.MoveRxButton = uibutton(app.UIFigure, 'push');
            app.MoveRxButton.ButtonPushedFcn = createCallbackFcn(app, @MoveRxButtonPushed, true);
            app.MoveRxButton.Text = 'MoveRx';
            app.RyLabel = uilabel(app.UIFigure);
            app.RyLabel.Text = 'Ry :';
            app.robotRy = uilabel(app.UIFigure);
            app.robotRy.Text = '';
            app.desiredRy = uieditfield(app.UIFigure, 'numeric');
            app.MoveRyButton = uibutton(app.UIFigure, 'push');
            app.MoveRyButton.ButtonPushedFcn = createCallbackFcn(app, @MoveRyButtonPushed, true);
            app.MoveRyButton.Text = 'MoveRy';
            app.RzLabel = uilabel(app.UIFigure);
            app.RzLabel.Text = 'Rz';
            app.robotRz = uilabel(app.UIFigure);
            app.robotRz.Text = '';
            app.desiredRz = uieditfield(app.UIFigure, 'numeric');
            app.MoveRzButton = uibutton(app.UIFigure, 'push');
            app.MoveRzButton.ButtonPushedFcn = createCallbackFcn(app, @MoveRzButtonPushed, true);
            app.MoveRzButton.Text = 'MoveRz';
            app.J1Label = uilabel(app.UIFigure);
            app.J1Label.Text = 'J1 :';
            app.robotJ1 = uilabel(app.UIFigure);
            app.robotJ1.Text = '';
            app.desiredJ1 = uieditfield(app.UIFigure, 'numeric');
            app.MoveJ1Button = uibutton(app.UIFigure, 'push');
            app.MoveJ1Button.ButtonPushedFcn = createCallbackFcn(app, @MoveJ1ButtonPushed, true);
            app.MoveJ1Button.Text = 'MoveJ1';
            app.J2Label = uilabel(app.UIFigure);
            app.J2Label.Text = 'J2 :';
            app.robotJ2 = uilabel(app.UIFigure);
            app.robotJ2.Text = '';
            app.desiredJ2 = uieditfield(app.UIFigure, 'numeric');
            app.MoveJ2Button = uibutton(app.UIFigure, 'push');
            app.MoveJ2Button.ButtonPushedFcn = createCallbackFcn(app, @MoveJ2ButtonPushed, true);
            app.MoveJ2Button.Text = 'MoveJ2';
            app.J3Label = uilabel(app.UIFigure);
            app.J3Label.Text = 'J3:';
            app.robotJ3 = uilabel(app.UIFigure);
            app.robotJ3.Text = '';
            app.desiredJ3 = uieditfield(app.UIFigure, 'numeric');
            app.MoveJ3Button = uibutton(app.UIFigure, 'push');
            app.MoveJ3Button.ButtonPushedFcn = createCallbackFcn(app, @MoveJ3ButtonPushed, true);
            app.MoveJ3Button.Text = 'MoveJ3';
            app.J4Label = uilabel(app.UIFigure);
            app.J4Label.Text = 'J4 :';
            app.robotJ4 = uilabel(app.UIFigure);
            app.robotJ4.Text = '';
            app.desiredJ4 = uieditfield(app.UIFigure, 'numeric');
            app.MoveJ4Button = uibutton(app.UIFigure, 'push');
            app.MoveJ4Button.ButtonPushedFcn = createCallbackFcn(app, @MoveJ4ButtonPushed, true);
            app.MoveJ4Button.Text = 'MoveJ4';
            app.J5Label = uilabel(app.UIFigure);
            app.J5Label.Text = 'J5 :';
            app.robotJ5 = uilabel(app.UIFigure);
            app.robotJ5.Text = '';
            app.desiredJ5 = uieditfield(app.UIFigure, 'numeric');
            app.MoveJ5Button = uibutton(app.UIFigure, 'push');
            app.MoveJ5Button.ButtonPushedFcn = createCallbackFcn(app, @MoveJ5ButtonPushed, true);
            app.MoveJ5Button.Text = 'MoveJ5';
            app.J6Label = uilabel(app.UIFigure);
            app.J6Label.Text = 'J6 :';
            app.robotJ6 = uilabel(app.UIFigure);
            app.robotJ6.Text = '';
            app.desiredJ6 = uieditfield(app.UIFigure, 'numeric');
            app.MoveJ6Button = uibutton(app.UIFigure, 'push');
            app.MoveJ6Button.ButtonPushedFcn = createCallbackFcn(app, @MoveJ6ButtonPushed, true);
            app.MoveJ6Button.Text = 'MoveJ6';
            app.MoveAllButton = uibutton(app.UIFigure, 'push');
            app.MoveAllButton.ButtonPushedFcn = createCallbackFcn(app, @MoveAllButtonPushed, true);
            app.MoveAllButton.Text = 'Move All';
            app.MoveAllJointsButton = uibutton(app.UIFigure, 'push');
            app.MoveAllJointsButton.ButtonPushedFcn = createCallbackFcn(app, @MoveAllJointsButtonPushed, true);
            app.MoveAllJointsButton.Text = 'Move All Joints';
            app.ConnectButton = uibutton(app.UIFigure, 'state');
            app.ConnectButton.ValueChangedFcn = createCallbackFcn(app, @ConnectButtonValueChanged, true);
            app.ConnectButton.Text = {'Connect'; ''};
            app.Gripper = uilabel(app.UIFigure);
            app.Gripper.Text = {'Gripper Control:'; ''};
            app.OpenButton = uibutton(app.UIFigure, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Text = {'Open'; ''};
            app.CloseButton = uibutton(app.UIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ControllerInterface

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end